classdef ABD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LaminateLayupLabel         matlab.ui.control.Label
        ABDMatrixLabel             matlab.ui.control.Label
        ABDTable                   matlab.ui.control.Table
        G12MPaEditField            matlab.ui.control.NumericEditField
        G12MPaEditFieldLabel       matlab.ui.control.Label
        PR12EditField              matlab.ui.control.NumericEditField
        PR12EditFieldLabel         matlab.ui.control.Label
        E2MPaEditField             matlab.ui.control.NumericEditField
        E2MPaEditFieldLabel        matlab.ui.control.Label
        E1MPaEditField             matlab.ui.control.NumericEditField
        E1MPaEditFieldLabel        matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        AddPlyButton               matlab.ui.control.Button
        ThicknessmmEditField       matlab.ui.control.NumericEditField
        ThicknessmmEditFieldLabel  matlab.ui.control.Label
        AngledegEditField          matlab.ui.control.NumericEditField
        AngledegEditFieldLabel     matlab.ui.control.Label
        PlyTable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.data = {'',''};
            app.PlyTable.Data %= app.data;
        end

        % Button pushed function: AddPlyButton
        function AddPlyButtonPushed(app, event)
            degree = app.AngledegEditField.Value;
            thickness = app.ThicknessmmEditField.Value;
            nr = {degree thickness};
            app.PlyTable.Data = [app.data;nr];
            app.data = app.PlyTable.Data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            format longG
            e1 = app.E1MPaEditField.Value;
            e2 = app.E2MPaEditField.Value;
            nu12 = app.PR12EditField.Value;
            g12 = app.G12MPaEditField.Value;
            thick = cell2mat(app.data(:,2));
            ply_angles = cell2mat(app.data(:,1));
            total_thick = sum(thick);

            n = max(size(ply_angles));
            z = zeros(1,n+1);
            for i = 1:n+1
                if i == 1
                    z(i) = -total_thick/2;
                else
                    z(i) = z(i-1) + thick(i-1);
                end
            end

            nu21 = nu12*(e2/e1);
            R = [1 0 0;0 1 0;0 0 2];
            Q = [e1/(1-nu12*nu21) nu12*e2/(1-nu12*nu21) 0;nu12*e2/(1-nu12*nu21) e2/(1-nu12*nu21) 0;0 0 g12];

            A = zeros(3,3);B = zeros(3,3);D = zeros(3,3);
            
            for k = 1:n
                theta = ply_angles(k);
                T = [cosd(theta)^2 sind(theta)^2 2*cosd(theta)*sind(theta);sind(theta)^2 cosd(theta)^2 ...
                    -2*cosd(theta)*sind(theta); -cosd(theta)*sind(theta) cosd(theta)*sind(theta) cosd(theta)^2-sind(theta)^2];
                A_temp = (inv(T)*Q*R*T*inv(R))*(z(k+1)-z(k));
                B_temp = (1/2) * (inv(T)*Q*R*T*inv(R))*(z(k+1)^2-z(k)^2);
                D_temp = (1/3) * (inv(T)*Q*R*T*inv(R))*(z(k+1)^3-z(k)^3);
                A = A+A_temp;
                B = B + B_temp;
                D = D + D_temp;
            end 
            
            ABD = [A B;B D];
            for i = 1:6
                for j = 1:6
                    if abs(ABD(i,j)) < 1e-6
                        ABD(i,j) = 0.0;
                    end
                end
            end


            app.ABDTable.Data = num2cell(ABD);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlyTable = uitable(app.UIFigure);
            app.PlyTable.ColumnName = {'Degree'; 'Thickness'};
            app.PlyTable.RowName = {};
            app.PlyTable.SelectionType = 'row';
            app.PlyTable.ColumnEditable = true;
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Text = 'Angle (deg)';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessmmEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.ThicknessmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddPlyButton = uibutton(app.UIFigure, 'push');
            app.AddPlyButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlyButtonPushed, true);
            app.AddPlyButton.Text = 'Add Ply';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.E1MPaEditFieldLabel = uilabel(app.UIFigure);
            app.E1MPaEditFieldLabel.Text = 'E1 (MPa)';
            app.E1MPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.E2MPaEditFieldLabel = uilabel(app.UIFigure);
            app.E2MPaEditFieldLabel.Text = 'E2 (MPa)';
            app.E2MPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PR12EditFieldLabel = uilabel(app.UIFigure);
            app.PR12EditFieldLabel.Text = 'PR12';
            app.PR12EditField = uieditfield(app.UIFigure, 'numeric');
            app.G12MPaEditFieldLabel = uilabel(app.UIFigure);
            app.G12MPaEditFieldLabel.Text = 'G12 (MPa)';
            app.G12MPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ABDTable = uitable(app.UIFigure);
            app.ABDTable.ColumnName = '';
            app.ABDTable.RowName = {''; ''};
            app.ABDMatrixLabel = uilabel(app.UIFigure);
            app.ABDMatrixLabel.Text = 'ABD Matrix';
            app.LaminateLayupLabel = uilabel(app.UIFigure);
            app.LaminateLayupLabel.Text = 'Laminate Layup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end